classdef ESP32_MQTT_DCmotor_New_Certificate_V1_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DCMotorControlTrainerAppUIFigure  matlab.ui.Figure
        ConnectButton                matlab.ui.control.Button
        StatusLabel                  matlab.ui.control.Label
        ConnectionTypeDropDown       matlab.ui.control.DropDown
        ConnectionTypeDropDownLabel  matlab.ui.control.Label
        TabGroup2                    matlab.ui.container.TabGroup
        SpeedVoltageTab              matlab.ui.container.Tab
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        CurrentErrorTab              matlab.ui.container.Tab
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes4                      matlab.ui.control.UIAxes
        DCMotorSignalsLabel          matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        StepTab                      matlab.ui.container.Tab
        Image56                      matlab.ui.control.Image
        Image33_9                    matlab.ui.control.Image
        Image53                      matlab.ui.control.Image
        Image52                      matlab.ui.control.Image
        InitialTimeLabel             matlab.ui.control.Label
        OffsetLabel                  matlab.ui.control.Label
        Image50_3                    matlab.ui.control.Image
        Image50_2                    matlab.ui.control.Image
        Image51                      matlab.ui.control.Image
        AmplitudeLabel_2             matlab.ui.control.Label
        Image50                      matlab.ui.control.Image
        Image33_8                    matlab.ui.control.Image
        Image21                      matlab.ui.control.Image
        warningtime                  matlab.ui.control.Label
        SignVtEditField              matlab.ui.control.NumericEditField
        warning                      matlab.ui.control.Label
        EditField                    matlab.ui.control.EditField
        DutyCycleSlider              matlab.ui.control.Slider
        DutyCycleLabel               matlab.ui.control.Label
        offsetEditField              matlab.ui.control.NumericEditField
        t0EditField                  matlab.ui.control.NumericEditField
        AmplitudeEditField           matlab.ui.control.NumericEditField
        SineTab                      matlab.ui.container.Tab
        Image55                      matlab.ui.control.Image
        Image54                      matlab.ui.control.Image
        Image33_7                    matlab.ui.control.Image
        EditField_2                  matlab.ui.control.NumericEditField
        Image47                      matlab.ui.control.Image
        TLabel_2                     matlab.ui.control.Label
        Image45_5                    matlab.ui.control.Image
        offsetLabel                  matlab.ui.control.Label
        phiLabel                     matlab.ui.control.Label
        FrequencyLabel               matlab.ui.control.Label
        Image45_4                    matlab.ui.control.Image
        Image45_3                    matlab.ui.control.Image
        Image45_2                    matlab.ui.control.Image
        AmplitudeLabel               matlab.ui.control.Label
        Image45                      matlab.ui.control.Image
        Image44                      matlab.ui.control.Image
        Image43                      matlab.ui.control.Image
        Image42                      matlab.ui.control.Image
        Image41                      matlab.ui.control.Image
        ButtonGroup_2                matlab.ui.container.ButtonGroup
        DiscreteButton_2             matlab.ui.control.RadioButton
        ContinuousButton_2           matlab.ui.control.RadioButton
        Image33_6                    matlab.ui.control.Image
        Image20                      matlab.ui.control.Image
        warning_2                    matlab.ui.control.Label
        offsetEditField_2            matlab.ui.control.NumericEditField
        phiEditField                 matlab.ui.control.NumericEditField
        omegaEditField               matlab.ui.control.NumericEditField
        AmplitudeEditField_2         matlab.ui.control.NumericEditField
        PIDTab                       matlab.ui.container.Tab
        Label                        matlab.ui.control.Label
        DigitalFiltersButton         matlab.ui.control.Button
        UpdateGainsButton            matlab.ui.control.Button
        Image60                      matlab.ui.control.Image
        Image59                      matlab.ui.control.Image
        FilterCoefficientNCheckBox   matlab.ui.control.CheckBox
        Image58                      matlab.ui.control.Image
        NEditField                   matlab.ui.control.NumericEditField
        FilterCoefficientLabel       matlab.ui.control.Label
        Image57                      matlab.ui.control.Image
        warning_3                    matlab.ui.control.Label
        Image40                      matlab.ui.control.Image
        Image39                      matlab.ui.control.Image
        Image38                      matlab.ui.control.Image
        Image37                      matlab.ui.control.Image
        TEditField                   matlab.ui.control.NumericEditField
        Image36                      matlab.ui.control.Image
        Image35                      matlab.ui.control.Image
        Image34                      matlab.ui.control.Image
        TLabel                       matlab.ui.control.Label
        Image25_6                    matlab.ui.control.Image
        Image22                      matlab.ui.control.Image
        ButtonGroup                  matlab.ui.container.ButtonGroup
        DiscreteButton               matlab.ui.control.RadioButton
        ContinuousButton             matlab.ui.control.RadioButton
        Image33_5                    matlab.ui.control.Image
        Image33_3                    matlab.ui.control.Image
        DerivativeTermLabel          matlab.ui.control.Label
        Image25_5                    matlab.ui.control.Image
        IntegralTermLabel            matlab.ui.control.Label
        Image25_4                    matlab.ui.control.Image
        ProportionalTermLabel        matlab.ui.control.Label
        Image25_3                    matlab.ui.control.Image
        Image29                      matlab.ui.control.Image
        Image28                      matlab.ui.control.Image
        Image27                      matlab.ui.control.Image
        KDEditField                  matlab.ui.control.NumericEditField
        KIEditField                  matlab.ui.control.NumericEditField
        KPEditField                  matlab.ui.control.NumericEditField
        GainsLabel                   matlab.ui.control.Label
        Image31                      matlab.ui.control.Image
        Image17                      matlab.ui.control.Image
        ErrorsignalLabel             matlab.ui.control.Label
        Image25_2                    matlab.ui.control.Image
        errorLabel                   matlab.ui.control.Label
        Image15                      matlab.ui.control.Image
        Image14                      matlab.ui.control.Image
        Image13                      matlab.ui.control.Image
        Image33                      matlab.ui.control.Image
        Image30                      matlab.ui.control.Image
        TargetSpeedLabel             matlab.ui.control.Label
        Image25                      matlab.ui.control.Image
        wdkEditField                 matlab.ui.control.NumericEditField
        SetVccEditField              matlab.ui.control.NumericEditField
        SetVccEditFieldLabel         matlab.ui.control.Label
        SystemOffLamp                matlab.ui.control.Lamp
        SystemOffLabel               matlab.ui.control.Label
        ControlPanel                 matlab.ui.container.Panel
        SaveButton                   matlab.ui.control.Button
        LoadButton                   matlab.ui.control.Button
        StopButton                   matlab.ui.control.Button
        StartButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        current =[];% Current in mA
        speed = [];% Speed in rad/s
        filtspeed = []; % Filtered Speed in rad/s
        filtcurrent = []; % Filtered current in mA
        time = [];% Time in s
        dutyCycle = []; 
        dutyCycleBuf = [];   % duty cycle (%) of each sample
        
        VCC = [];
        voltage = [];
        current_temp = [];
        speed_temp = [];
        filtspeed_temp = [];
        filtcurrent_temp = [];
        filt = [];
        time_temp = [];
        dutyCycle_temp = [];
        Vt_temp = [];
        Vt = [];
        error;
        error_temp;
        omega0speed  
        omega0current
        omega0speed_rad
        omega0current_rad
        filtercurrent
        filterspeed
        to
        Data
        timeX
        currentY
        speedY
        filtspeedY
        filtcurrentY
        voltageY
        errorY
        dutyCycleY
        dataOffset
        checkboxValue
        vcc = 0;
        amplitude_new =[];
        offset_new = [];
        signVt
        signVdt
        valueVt
        signAmplitude
        amplitudechg
        tcpObj
        selectedConnectionType % either "MQTT Broker" or "WiFi Computer"
        raw
        espClient
        % ESP32IPLabel
        Running = false;
        AlreadySubscribed = false;

        brokerAddress = 'ssl://YOUR_CLUSTER_ID.s1.eu.hivemq.cloud';
        port = 8883;
        rootCert = fullfile(fileparts(mfilename('fullpath')), 'isrgrootx1.pem');
        clientID = 'YOUR_CLIENT_ID';
        Username = 'YOUR_MQTT_USERNAME';
        Password = 'YOUR_MQTT_PASSWORD';

        MQTTSignal  % Property to hold the MQTT client
        Topic       % Property to hold the topic name
        Topic2      % Property to hold the second topic name

        
    end
    
    methods (Access = public)
        
        function app = warningmsg
            % Set the Visible property of the Label to 'off' initially
            app.warning.Visible = 'off';
        end
        
        function app = warningtimemsg
            % Set the Visible propertu of the Label to 'off' initially
            app.warningtime.Visible = 'off';
        end
        
        function app = warning2msg
            % Set the Visible property of the Label to 'off' initially
            app.warning2.Visible = 'off';
        end
        
        function app = warning3msg
            % Set the Visible property of the Label to 'off' initially
            app.warning3.Visible = 'off';
        end
        
        function updatePID(app)

            if strcmpi(app.StartButton.Enable,'off') % Ensure system is running
                data = struct('VCC', app.SetVccEditField.Value, 'interruptor', 'on', 'Type', 'PID', ...
                              'Kp', app.KPEditField.Value, 'Ki', app.KIEditField.Value, ...
                              'Kd', app.KDEditField.Value, 'des_speed', app.wdkEditField.Value, ...
                              'Discrete', app.DiscreteButton.Value, 'Continuous', app.ContinuousButton.Value, ...
                              'T', app.TEditField.Value, 'N', app.NEditField.Value, ...
                              'omega0', app.omega0speed,'omega0c',app.omega0current, 'filt', app.filterspeed, ...
                              'filtc', app.filtercurrent);
                jsonData = jsonencode(data);
                % Send new PID values through the active connection (MQTT or TCP)
                if strcmp(app.ConnectionTypeDropDown.Value, 'MQTT Broker')
                    write(app.MQTTSignal, app.Topic, jsonData);
                else
                    writeline(app.espClient, jsonData);
                end
            end            
        end

        function updateFilters(app) 
            if strcmpi(app.StartButton.Enable, 'off') % Ensure System is running before updating
                % Create a data structure with filter settings
                data = struct('VCC', app.SetVccEditField.Value, 'interruptor', 'on', 'Type', 'PID', ...
                              'Kp', app.KPEditField.Value, 'Ki', app.KIEditField.Value, ...
                              'Kd', app.KDEditField.Value, 'des_speed', app.wdkEditField.Value, ...
                              'Discrete', app.DiscreteButton.Value, 'Continuous', app.ContinuousButton.Value, ...
                              'T', app.TEditField.Value, 'N', app.NEditField.Value, ...
                              'omega0', app.omega0speed, 'omega0c', app.omega0current, 'filt', app.filterspeed, ...
                              'filtc', app.filtercurrent);
                
                % Encode the structure as JSON
                jsonData = jsonencode(data);
                
                % Send the updated filter values through the active connection (MQTT or TCP)
                if strcmp(app.ConnectionTypeDropDown.Value, 'MQTT Broker')
                    write(app.MQTTSignal, app.Topic, jsonData);
                else
                    writeline(app.espClient, jsonData);
                end
            end
        end


        function submitCheckboxData(app, popup, checkbox1, checkbox2, inputField1, inputField2)
            % filter values from pop-up window
            app.filtercurrent = checkbox1.Value;
            app.filterspeed = checkbox2.Value;
            app.omega0current = inputField1.Value;
            app.omega0speed = inputField2.Value;
        
            if app.ContinuousButton.Value
                % Fixed limits for continuous systems
                if app.omega0current > 100 || app.omega0speed > 100
                    % Display a warning message
                    uialert(popup, 'Current cutoff frequency cannot exceed 100 Hz, and speed cutoff frequency cannot exceed 100 Hz.', ...
                            'Warning', 'Icon', 'warning');
                    return; % Stop further execution if the warning is triggered
                end
            elseif app.DiscreteButton.Value
                if app.omega0current > 100 || app.omega0speed > 100
                    % Display a warning message
                    uialert(popup, 'Current cutoff frequency cannot exceed 100 Hz, and speed cutoff frequency cannot exceed 100 Hz.', ...
                            'Warning', 'Icon', 'warning');
                    return; % Stop further execution if the warning is triggered
                end
            end

        
            % Update the system with the new filter values
            updateFilters(app);
        
            % Close the pop-up
            close(popup);
        end  


    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % app.DisconnectButton.Enable = 'off';
            app.StatusLabel.Text = "Select connection method and press Connect.";
            app.StatusLabel.FontColor = [1.00, 0.00, 0.00]; 
            app.ConnectionTypeDropDown.Value = 'MQTT Broker';

            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.dataOffset = [];
            app.Vt = [];
        
            % Change status to "on"
            app.SystemOffLamp.Color = 'g';
            app.SystemOffLabel.Text = 'System On';

            selectedTab = app.TabGroup.SelectedTab;
        
            % Warning message for Sample Time when Discrete
            if app.DiscreteButton.Value == 1 && app.TEditField.Value < 0.005
                uialert(app.DCMotorControlTrainerAppUIFigure, ...
                        'Sample Time value cannot be smaller than 0.005.', ...
                        'Warning', 'Icon', 'warning');
                return;
            end
        
            % Warning message for filter coefficient N
            nValue = app.NEditField.Value;
            if ~isinteger(nValue) && nValue ~= round(nValue)
                uialert(app.DCMotorControlTrainerAppUIFigure, 'N value must be an integer.', 'Input Error');
                return;
            end
        
            flag = true;
            set(app.StartButton, 'Enable', 'off'); % Force into loop
            set(app.StopButton, 'Enable', 'on');
            set(app.SaveButton, 'Enable', 'off');
            set(app.LoadButton, 'Enable', 'off');
        
            app.wdkEditField.Enable = 'off';
            app.DutyCycleSlider.Enable = 'off';
            app.EditField.Enable = 'off';
            app.AmplitudeEditField.Enable='off';
            app.offsetEditField.Enable='off';
            app.t0EditField.Enable='off';
        
            clear app.current app.speed app.vcc app.time
            app.current = [];
            app.speed = [];
            app.filtspeed = [];
            app.filtcurrent = []; 
            app.VCC = [];
            app.time = [];
            app.voltage = [];
            app.error = [];
            app.dutyCycleBuf = [];
        
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);
        
            % Prepare JSON data to send based on selected tab
            if selectedTab == app.StepTab
                app.UpdateGainsButton.Enable='off';
                data = struct('VCC',app.SetVccEditField.Value,'interruptor','on','Type','Step','A',app.AmplitudeEditField.Value,'t0',app.t0EditField.Value,'offset',app.offsetEditField.Value);
            elseif selectedTab == app.SineTab
                app.UpdateGainsButton.Enable='off';
                data = struct('VCC',app.SetVccEditField.Value,'interruptor','on','Type','Sine','A',app.AmplitudeEditField_2.Value,'omega',app.omegaEditField.Value,'phi',app.phiEditField.Value,'offset',app.offsetEditField_2.Value,'Discrete',app.DiscreteButton_2.Value,'Continuous',app.ContinuousButton_2.Value,'T',app.EditField_2.Value);

            else % PID Tab
                app.UpdateGainsButton.Enable='on';
                data = struct('VCC',app.SetVccEditField.Value,'interruptor','on','Type','PID','Kp',app.KPEditField.Value,'Ki',app.KIEditField.Value,'Kd',app.KDEditField.Value,'des_speed',app.wdkEditField.Value,'Discrete',app.DiscreteButton.Value,'Continuous',app.ContinuousButton.Value,'T',app.TEditField.Value,'N',app.NEditField.Value,'omega0',app.omega0speed,'omega0c',app.omega0current,'filt',app.filterspeed,'filtc',app.filtercurrent);
            end
        
            jsonData = jsonencode(data);
        
            % Connection selection
            connectionType = app.ConnectionTypeDropDown.Value;
        
            if strcmp(connectionType, 'MQTT Broker')
                % 1) Subscribe BEFORE starting, so the beginning is not lost
                if ~app.AlreadySubscribed
                    subscribe(app.MQTTSignal, app.Topic2);
                    app.AlreadySubscribed = true;
                    pause(0.2);
                end
                % 2) Discard messages left over from a previous test
                try
                    read(app.MQTTSignal);
                catch
                end
                % 3) Start the experiment
                write(app.MQTTSignal, app.Topic, jsonData);
                pause(0.5);

            elseif strcmp(connectionType, 'ESP32 WiFi')
                % Discard old data in the input buffer
                try
                    flush(app.espClient, "input");
                catch
                end
                writeline(app.espClient, jsonData);
                pause(0.5);
            end
                
            offset = [];
            app.dataOffset = [];
            app.Running = true;
        
            % MAIN LOOP
            while app.Running 
                
                selectedTab = app.TabGroup.SelectedTab;

                % strcmpi(app.StartButton.Enable,'off') && flag == 1
                connectionType = app.ConnectionTypeDropDown.Value;

                % ---------- Receive lines (one per sample) ----------
                lineas = strings(0,1);

                if strcmp(connectionType, 'MQTT Broker')
                    try
                        dataTT = read(app.MQTTSignal);      % all pending messages
                        if ~isempty(dataTT)
                            msgs = string(dataTT.Data);
                            lineas = splitlines(strjoin(msgs, newline));
                        end
                    catch
                        lineas = strings(0,1);
                    end

                elseif strcmp(connectionType, 'ESP32 WiFi')
                    if isvalid(app.espClient)
                        while app.espClient.NumBytesAvailable > 0   % all pending lines
                            lineas(end+1,1) = readline(app.espClient); %#ok<AGROW>
                        end
                    else
                        disp('ESP32 TCP client not connected.');
                    end
                end

                % Keep only complete lines with 8 fields
                lineas = strtrim(lineas);
                lineas = lineas(lineas ~= "" & count(lineas, ",") == 7);

                if isempty(lineas)
                    pause(0.2);      % no new data
                    continue;
                end

                str = split(lineas, ",");          % N x 8
                if isscalar(lineas)
                    str = reshape(str, 1, []);     % 1 x 8 when there is only one line
                end

                % ---------- Split fields ----------
                app.current_temp     = str2double(str(:,1));
                app.speed_temp       = str2double(str(:,2));
                app.time_temp        = str2double(str(:,3));   % real time from the ESP32
                app.Vt_temp          = str2double(str(:,4));
                app.filtspeed_temp   = str2double(str(:,5));
                app.filtcurrent_temp = str2double(str(:,6));
                duty_temp            = str2double(str(:,7));
                app.error_temp       = str2double(str(:,8));

                app.dutyCycle = duty_temp(end);        % last value, for the slider

                % ---------- Append to the buffers ----------
                app.current      = [app.current;      app.current_temp];
                app.speed        = [app.speed;        app.speed_temp];
                app.time         = [app.time;         app.time_temp];
                app.Vt           = [app.Vt;           app.Vt_temp];
                app.filtspeed    = [app.filtspeed;    app.filtspeed_temp];
                app.filtcurrent  = [app.filtcurrent;  app.filtcurrent_temp];
                app.error        = [app.error;        app.error_temp];
                app.dutyCycleBuf = [app.dutyCycleBuf; duty_temp];

                offset = app.time(1);
                app.dataOffset = app.time - offset;

                app.DutyCycleSlider.Limits = [0 100];
                app.DutyCycleSlider.Value = max(min(abs(app.dutyCycle), 100), 0);
                app.EditField.Value = [int2str(abs(app.dutyCycle)) ' %'];        
                
                % Define the time window (last 20 seconds)
                timeWindow = 20; % seconds
                if ~isempty(app.dataOffset)
                    % Get the maximum time and calculate the start of the time window
                    maxTime = max(app.dataOffset);
                    timeStart = maxTime - timeWindow;
                
                    % Find indices of data within the time window
                    validIndices = app.dataOffset >= timeStart;

                    % Plot only 1 out of every 4 points (saved data does not change)
                    idxPlot = find(validIndices);
                    validIndices(:) = false;
                    validIndices(idxPlot(1:4:end)) = true;

                
                    % Filter data to only include the last 20 seconds
                    dataOffsetFiltered = app.dataOffset(validIndices);
                    currentFiltered = app.current(validIndices);
                    speedFiltered = app.speed(validIndices);
                    VtFiltered = app.Vt(validIndices);
                    filtspeedFiltered = app.filtspeed(validIndices);
                    filtcurrentFiltered = app.filtcurrent(validIndices);
                    errorFiltered = app.error(validIndices);
                
                    % Clear axes before plotting
                    cla(app.UIAxes);
                    cla(app.UIAxes2);
                    cla(app.UIAxes3);
                    cla(app.UIAxes4);
                
                    % Plot filtered data
                    if selectedTab == app.StepTab || selectedTab == app.SineTab
                        % Voltage signal name according to the selected mode
                        if selectedTab == app.StepTab
                            voltageName = 'Step Input V(t)';
                        else
                            voltageName = 'Sine Input V(t)';
                        end

                        % Current
                        plot(app.UIAxes, dataOffsetFiltered, currentFiltered, 'b');
                        legend(app.UIAxes, 'Measured Current', 'FontSize', 7, 'Box', 'off');

                        % Speed
                        plot(app.UIAxes2, dataOffsetFiltered, speedFiltered, 'b');
                        legend(app.UIAxes2, 'Measured Speed', 'FontSize', 7, 'Box', 'off');

                        % Applied voltage
                        plot(app.UIAxes3, dataOffsetFiltered, VtFiltered, 'b');
                        legend(app.UIAxes3, voltageName, 'FontSize', 7, 'Box', 'off');

                        % Open loop: there is no error signal to plot
                        legend(app.UIAxes4, 'off');
                        text(app.UIAxes4, 0.5, 0.5, 'Open loop: no error signal', ...
                            'Units', 'normalized', 'HorizontalAlignment', 'center', ...
                            'FontSize', 10, 'Color', [0.5 0.5 0.5]);

                        % Sign of the applied voltage (Step mode only)
                        if selectedTab == app.StepTab
                            app.SignVtEditField.Value = sign(VtFiltered(end));
                        end
                
                    else % PID Tab
                        % Limit voltage values to the range [-12, 12]
                        voltageLimFiltered = max(min(VtFiltered, 12), -12);
                
                        % Plot voltage
                        plot(app.UIAxes3, dataOffsetFiltered, VtFiltered, 'r', ...
                             dataOffsetFiltered, voltageLimFiltered, 'b');
                        legend(app.UIAxes3, 'U(t) Voltage', 'Input Voltage', 'FontSize', 7, 'Box', 'off');
                
                        % Plot error
                        plot(app.UIAxes4, dataOffsetFiltered, errorFiltered, 'g');
                        legend(app.UIAxes4, 'Error (e)', 'FontSize', 7, 'Box', 'off');

                        % Get lengths of the vectors
                        lenDataOffset = length(dataOffsetFiltered);
                        lenCurrent = length(currentFiltered);
                        lenFiltCurrent = length(filtcurrentFiltered);
                        
                        % Find the minimum length among the vectors
                        minLength = min([lenDataOffset, lenCurrent, lenFiltCurrent]);
                        
                        % Adjust the vectors to the same length (truncate them if necessary)
                        dataOffsetFiltered = dataOffsetFiltered(1:minLength);
                        currentFiltered = currentFiltered(1:minLength);
                        filtcurrentFiltered = filtcurrentFiltered(1:minLength);

                
                        % Plot current
                        if app.filtercurrent == 0
                            plot(app.UIAxes, dataOffsetFiltered, currentFiltered);
                            legend(app.UIAxes, 'Unfiltered', 'FontSize', 7, 'Box', 'off');
                        elseif app.filtercurrent == 1
                            plot(app.UIAxes, ...
                                 dataOffsetFiltered, currentFiltered, 'b', ...
                                 dataOffsetFiltered, filtcurrentFiltered, 'r');
                            legend(app.UIAxes, 'Unfiltered', 'Filtered', 'FontSize', 7, 'Box', 'off');
                        end
                
                        % Plot speed
                        if app.filterspeed == 0
                            plot(app.UIAxes2, dataOffsetFiltered, speedFiltered);
                            legend(app.UIAxes2, 'Unfiltered', 'FontSize', 7, 'Box', 'off');
                        else
                            plot(app.UIAxes2, dataOffsetFiltered, speedFiltered, 'b', ...
                                 dataOffsetFiltered, filtspeedFiltered, 'r');
                            legend(app.UIAxes2, 'Unfiltered', 'Filtered', 'FontSize', 7, 'Box', 'off');
                        end
                    end
                
                    drawnow;
                    pause(0.5);
                end
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.Running = false;
            
            app.AlreadySubscribed = false;

            app.ConnectButton.Enable = 'on';

            connectionType = app.ConnectionTypeDropDown.Value;

            if strcmp(connectionType, "MQTT Broker")
                try
                    unsubscribe(app.MQTTSignal, "Topic", app.Topic2);
                    app.AlreadySubscribed = false;
                catch e
                    disp("Unsubscribe failed: " + e.message);
                end
            elseif strcmp(connectionType, "ESP32 WiFi")
                % Nothing special for TCP, just stop reading
            end
            
            app.Running = false;


            set(app.StartButton,'Enable','on')
            set(app.StopButton,'Enable','off')
            set(app.SaveButton,'Enable','on')
            set(app.LoadButton,'Enable','on')
            
            app.wdkEditField.Enable = 'on';
            app.UpdateGainsButton.Enable='off';
            app.DutyCycleSlider.Enable = 'off';
            app.EditField.Enable = "off";
            app.AmplitudeEditField.Enable='on';
            app.offsetEditField.Enable='on';
            app.t0EditField.Enable='on';
            flag = 0;
    
            % Change status to "off"
            app.SystemOffLamp.Color = 'r';
            app.SystemOffLabel.Text = 'System Off';
            
            data = struct('interruptor','off');
            jsonData = jsonencode(data);

            % Send based on selected connection
            if strcmp(app.ConnectionTypeDropDown.Value, "MQTT Broker")
                try
                    if isvalid(app.MQTTSignal)
                        write(app.MQTTSignal, app.Topic, jsonData);
                        disp("Sent 'off' message via MQTT.");
                    else
                        disp("MQTT client invalid during Stop.");
                    end
                    set(app.SaveButton,'Enable','on');
                    app.DutyCycleSlider.Enable = 'on';
                catch ME
                    disp("MQTT Stop error: " + ME.message);
                end
         
            elseif strcmp(app.ConnectionTypeDropDown.Value, "ESP32 WiFi")
                try
                    if isvalid(app.espClient)
                        writeline(app.espClient, jsonData);
                        disp("Sent 'off' message via TCP.");
                        clear app.espClient;  % Close TCP connection
                        disp("TCP connection closed.");
                    else
                        disp("TCP client invalid during Stop.");
                    end
                catch ME
                    disp("TCP Stop error: " + ME.message);
                end
            end
            
        end

        % Value changed function: DutyCycleSlider
        function DutyCycleSliderValueChanged(app, event)
            % Check the sign of Amplitude
            signAmplitude = sign(app.AmplitudeEditField.Value);
            
            if signAmplitude == 1
                dutyCycle = round(app.DutyCycleSlider.Value);
                app.EditField.Value = [num2str(abs(dutyCycle)) ' %'];
                
                offset_new = app.offsetEditField.Value;
                Vdt = dutyCycle/100 * app.SetVccEditField.Value;
                newAmplitude = Vdt - offset_new;
                app.AmplitudeEditField.Value = abs(newAmplitude);
            end

            if signAmplitude == -1
                dutyCycle = -abs(round(app.DutyCycleSlider.Value));
                app.EditField.Value = [num2str(abs(dutyCycle)) ' %'];
                offset_new = app.offsetEditField.Value;
                Vdt = dutyCycle/100 * app.SetVccEditField.Value;
                newAmplitude = Vdt + offset_new;
                app.AmplitudeEditField.Value = newAmplitude;
            end
                
            if abs(Vdt) > app.SetVccEditField.Value || abs(offset_new) > app.SetVccEditField.Value
                 app.warning.Visible = 'on';
                 app.warning.Text = 'V(t) Output Value Exceeds Vcc Input Value.';
                 set(app.StartButton,'Enable','off');
            end
        end

        % Value changed function: AmplitudeEditField
        function AmplitudeEditFieldValueChanged(app, event)
            set(app.StartButton,'Enable','on');
            app.warning.Visible = 'off';
            amplitude_new = app.AmplitudeEditField.Value;
            offset_new = app.offsetEditField.Value;
                       
            Vdt = amplitude_new + offset_new;

            if app.t0EditField.Value > 0 
                signVdt = sign(app.offsetEditField.Value);
                app.SignVtEditField.Value = signVdt;          

            elseif app.t0EditField.Value == 0 
                signVdt = sign(Vdt);
                app.SignVtEditField.Value = signVdt;
            end

            if abs(Vdt) > app.SetVccEditField.Value || abs(offset_new) > app.SetVccEditField.Value 
                app.warning.Visible = 'on';
                app.warning.Text = 'V(t) Output Value Exceeds Vcc Input Value.';
                set(app.StartButton,'Enable','off');
            else
                app.warning.Visible = 'off';
                set(app.StartButton,'Enable','on');
                app.DutyCycleSlider.Value = abs(Vdt/app.SetVccEditField.Value * 100);
                dutyCycle = round(app.DutyCycleSlider.Value);
                app.EditField.Value = [num2str(abs(dutyCycle)) ' %'];             
            end             
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)

            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            
            % [file,path] = uigetfile('*.mat');
            [file,path] = uigetfile('*.mat','Load Experiment');
            
            if isequal(file,0)
                return;
            end
        
            filedata = load(fullfile(path,file));

            data = filedata.data;
            
            % filedata = load(file);
            
            app.timeX       = data.Time;
            app.speedY      = data.Speed;
            app.filtspeedY  = data.FiltSpeed;
            app.currentY    = data.Current;
            app.filtcurrentY = data.FiltCurrent;
            app.voltageY    = data.Voltage;
            app.errorY = data.Error;
            app.dutyCycleY  = data.DutyCycle;
        
            % Current
            plot(app.UIAxes, ...
                 app.timeX, app.currentY, 'b', ...
                 app.timeX, app.filtcurrentY, 'r');
            
            legend(app.UIAxes, 'Unfiltered', 'Filtered', ...
                   'FontSize', 7, 'Box', 'off');
            
            % Speed
            plot(app.UIAxes2, ...
                 app.timeX, app.speedY, 'b', ...
                 app.timeX, app.filtspeedY, 'r');
            
            legend(app.UIAxes2, 'Unfiltered', 'Filtered', ...
                   'FontSize', 7, 'Box', 'off');
            
            % Voltage
            plot(app.UIAxes3, app.timeX, app.voltageY, 'b');
            
            legend(app.UIAxes3, 'Input Voltage', ...
                   'FontSize', 7, 'Box', 'off');
            
            % Error
            plot(app.UIAxes4, app.timeX, app.errorY, 'g');
            
            legend(app.UIAxes4, 'Error (e)', ...
                   'FontSize', 7, 'Box', 'off');

            % app.timeX = filedata.data(:,3);
            % app.currentY = filedata.data(:,1);
            % app.speedY = filedata.data(:,2);        
            % plot(app.UIAxes,app.timeX,app.currentY,'b');
            % plot(app.UIAxes2,app.timeX,app.speedY,'r');     
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            data = table( ...
                app.dataOffset(:), ...
                app.speed(:), ...
                app.filtspeed(:), ...
                app.current(:), ...
                app.filtcurrent(:), ...
                app.Vt(:), ...
                app.error(:), ...
                app.dutyCycleBuf(:), ...
                'VariableNames', ...
                {'Time','Speed','FiltSpeed','Current','FiltCurrent',...
                'Voltage','Error','DutyCycle'});


            [file,path] = uiputfile('*.mat');
            figure(app.DCMotorControlTrainerAppUIFigure);

            if isequal(file,0)
                return;
            end

            date = datetime('now');

            save(fullfile(path,file),'data','date');

        end

        % Value changed function: offsetEditField
        function offsetEditFieldValueChanged(app, event)
            offset_new = app.offsetEditField.Value;
            amplitude_new = app.AmplitudeEditField.Value;

            Vdt = amplitude_new + offset_new;

            if app.t0EditField.Value > 0 
                signVdt = sign(app.offsetEditField.Value);
                app.SignVtEditField.Value = signVdt;          

            elseif app.t0EditField.Value == 0 
                signVdt = sign(Vdt);
                app.SignVtEditField.Value = signVdt;    
            end

            if abs(Vdt) > app.SetVccEditField.Value || abs(offset_new) > app.SetVccEditField.Value
               app.warning.Visible = 'on';
               app.warning.Text = 'V(t) Output Value Exceeds Vcc Input Value.';
               set(app.StartButton,'Enable','off');
             else 
                 app.warning.Visible = 'off';
                 set(app.StartButton,'Enable','on');
                 app.DutyCycleSlider.Value = abs(Vdt/app.SetVccEditField.Value * 100);
                 dutyCycle = round(app.DutyCycleSlider.Value);
                 app.EditField.Value = [num2str(abs(dutyCycle)) ' %'];
             end         
        end

        % Value changed function: t0EditField
        function t0EditFieldValueChanged(app, event)
            if app.t0EditField.Value < 0
                app.warningtime.Visible = 'on';
                app.warningtime.Text = 'time cannot be negative';
            else
                app.warningtime.Visible = 'off';
            end

            offset_new = app.offsetEditField.Value;
            amplitude_new = app.AmplitudeEditField.Value;

            Vdt = amplitude_new + offset_new;
            
            if app.t0EditField.Value > 0 
                signVdt = sign(app.offsetEditField.Value);
                app.SignVtEditField.Value = signVdt;          

            elseif app.t0EditField.Value == 0 
                signVdt = sign(Vdt);
                app.SignVtEditField.Value = signVdt;    
            end
        end

        % Value changed function: AmplitudeEditField_2
        function AmplitudeEditField_2ValueChanged(app, event)
            set(app.StartButton,'Enable','on');
            app.warning_2.Visible = 'off';
            offset_new2 = app.offsetEditField_2.Value;
            amplitude_new2 = app.AmplitudeEditField_2.Value;
            
            Vdt = amplitude_new2 + offset_new2;

            %app.signVdt = sign(Vdt);
              
            if abs(Vdt) > app.SetVccEditField.Value || abs(offset_new2) > app.SetVccEditField.Value
               app.warning_2.Visible = 'on';
               app.warning_2.Text = 'V(t) Output Value Exceeds Vcc Input Value.';
               set(app.StartButton,'Enable','off');
            else
               app.warning.Visible = 'off';
               set(app.StartButton,'Enable','on');
            end
        end

        % Value changed function: offsetEditField_2
        function offsetEditField_2ValueChanged(app, event)
            offset_new2 = app.offsetEditField_2.Value;
            amplitude_new2 = app.AmplitudeEditField_2.Value;
            Vdt = amplitude_new2 + offset_new2;
            
            if abs(Vdt) > app.SetVccEditField.Value || abs(offset_new2) > app.SetVccEditField.Value
               app.warning_2.Visible = 'on';
               app.warning_2.Text = 'V(t) Output Value Exceeds Vcc Input Value.';
               set(app.StartButton,'Enable','off');
            else 
                 app.warning_2.Visible = 'off';
                 set(app.StartButton,'Enable','on');
            end   
        end

        % Value changed function: SetVccEditField
        function SetVccEditFieldValueChanged(app, event)

            if app.SetVccEditField  ~= 0
                app.warning.Visible = 'off';
                app.warning_2.Visible = 'off';
                app.warning_3.Visible = 'off';
            end
            set(app.StartButton,'Enable','on');            
        end

        % Value changed function: wdkEditField
        function wdkEditFieldValueChanged(app, event)
            set(app.StartButton,'Enable','on');
            app.warning_3.Visible = 'off';          
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            %selectedButton = app.ButtonGroup.SelectedObject;
            if app.ContinuousButton.Value == 1
                app.Image34.Visible = 'on';
                app.Image35.Visible = 'on';
                app.Image36.Visible = 'on';
                app.Image38.Visible = 'on';
                app.Image39.Visible = 'on';
                app.Image40.Visible = 'on';
                app.Image17.Visible = 'off';
                app.Image22.Visible = 'off';% set Vk equation visible
                app.Image25_6.Visible = 'on';
                app.Image30.Visible = 'off';
                app.TEditField.Visible = 'off';
                app.Image37.Visible = 'off';
                app.Image13.Visible = 'off';
                app.Image14.Visible = 'off';
                app.Image15.Visible = 'off';
                app.Image57.Visible = 'off';
                app.FilterCoefficientLabel.Visible = 'off';
                app.FilterCoefficientNCheckBox.Visible = 'off';
                app.Image58.Visible = 'off';
                app.NEditField.Visible = 'off';
                app.Image59.Visible = 'off';
                app.Image60.Visible = 'off';
                app.Image37.Visible = 'on';
                
                app.Label.Visible = 'on';
                

                % app.Image57_2.Visible = 'off';
                % app.BlackRectangle.Visible = 'off';
                % app.w0EditField.Visible = 'off';
                % app.SpeedFilterCheckBox.Value = false;
            end
            if app.DiscreteButton.Value == 1 
                app.FilterCoefficientNCheckBox.Value = false;
                app.Image22.Visible = 'on';
                app.Image25_6.Visible = 'on';
                app.Image30.Visible = 'on';
                app.Image13.Visible = 'on';
                app.Image14.Visible = 'on';
                app.Image36.Visible = 'off';
                app.Image17.Visible = 'on';
                app.Image34.Visible = 'off';
                app.Image38.Visible = 'off';
                app.Image39.Visible = 'off';
                app.Image40.Visible = 'off';
                app.TEditField.Visible = 'on';
                app.Image37.Visible = 'on';
                app.FilterCoefficientNCheckBox.Visible = 'on';
                app.Image59.Visible = 'on';
                app.Image35.Visible = 'off';
                app.Image60.Visible = 'on';
                app.Image37.Visible = 'on';
                
                app.Label.Visible = 'off';
                

            end
            
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            if app.ContinuousButton_2.Value == 1 
                app.Image20.Visible = 'on';
                app.Image45_5.Visible = 'off';
                app.Image47.Visible = 'off';
                app.Image54.Visible = 'off';
                app.Image55.Visible = 'off';
                app.EditField_2.Visible = 'off';            
            end

            if app.DiscreteButton_2.Value == 1              
                app.Image45_5.Visible = 'on';
                app.Image47.Visible = 'on';
                app.EditField_2.Visible = 'on';
                app.Image54.Visible = 'on';
                app.Image55.Visible = 'on';
                app.Image20.Visible = 'off';
            end       
        end

        % Value changed function: FilterCoefficientNCheckBox
        function FilterCoefficientNCheckBoxValueChanged(app, event)
            Filter_N = app.FilterCoefficientNCheckBox.Value;
            if Filter_N == true
                app.Image57.Visible = 'on';
                app.FilterCoefficientLabel.Visible = 'on';
                app.Image58.Visible = 'on';
                app.NEditField.Visible = 'on';
                app.Image60.Visible = 'off';
                app.Image15.Visible = 'on';
            else
                app.NEditField.Value = 0;
                app.Image57.Visible = 'off';
                app.FilterCoefficientLabel.Visible = 'off';
                app.Image58.Visible = 'off';
                app.NEditField.Visible = 'off';
                app.Image15.Visible = 'off';
                app.Image60.Visible = 'on';
            end            
        end

        % Callback function
        function SpeedFilterCheckBoxValueChanged(app, event)
            Speed_Filter = app.SpeedFilterCheckBox.Value;
            Current_Filter = app.CurrentFilterCheckBox.Value;
            if Speed_Filter == true && Current_Filter == false
                app.Image57_2.Visible = 'on';
                app.BlackRectangle.Visible = 'on';
                app.w0EditField.Visible = 'on';
            elseif Speed_Filter == false && Current_Filter == false
                app.filtspeed = [];
                app.Image57_2.Visible = 'off';
                app.BlackRectangle.Visible = 'off';
                app.BlackRectangle.Visible = 'off';
                app.w0EditField.Visible = 'off';
                app.w0EditField.Value = 0;
            end           
        end

        % Callback function
        function CurrentFilterCheckBoxValueChanged(app, event)
            Current_Filter = app.CurrentFilterCheckBox.Value;
            Speed_Filter = app.SpeedFilterCheckBox.Value;
            if Current_Filter == true && Speed_Filter == false
                app.Image57_2.Visible = 'on';
                app.BlackRectangle.Visible = 'on';
                app.w0EditField.Visible = 'on';
            elseif Current_Filter == false && Speed_Filter == false
                app.Image57_2.Visible = 'off';
                app.BlackRectangle.Visible = 'off';
                app.BlackRectangle.Visible = 'off';
                app.w0EditField.Visible = 'off';
                app.w0EditField.Value = 0;
            end        
        end

        % Button pushed function: UpdateGainsButton
        function UpdateGainsButtonPushed(app, event)
            updatePID(app); % Send updated PID values
        end

        % Button pushed function: DigitalFiltersButton
        function DigitalFiltersButtonPushed(app, event)
            % Create a new pop-up window
            popup = uifigure('Name', 'Digital Filters', 'Position', [900, 400, 350, 275]);
            
            % Adding checkboxes to the pop-up window and initialize with stored values
            checkbox1 = uicheckbox(popup, 'Position', [30, 220, 150, 30], 'Text', 'Current Filter:', ...
                                   'Value', app.filtercurrent); % Load stored value
            checkbox2 = uicheckbox(popup, 'Position', [30, 180, 150, 30], 'Text', 'Speed Filter:', ...
                                   'Value', app.filterspeed); % Load stored value
            
            % Add label, image, and input field for current cutoff frequency
            lblInput1 = uilabel(popup, 'Position', [30, 140, 150, 30], 'Text', 'Current Cutoff Frequency:');
            lblInput1.BackgroundColor = 'black';
            lblInput1.FontColor = 'white';
            img1 = uiimage(popup, 'Position', [190, 140, 30, 30]);
            img1.ImageSource = fullfile(fileparts(mfilename('fullpath')), 'CutOffFrequency1.png');
            inputField1 = uieditfield(popup, 'numeric', 'Position', [225, 140, 100, 30], ...
                                      'Value', app.omega0current); % Load stored value
            %inputField1.ValueDisplayFormat = '%.2f Hz';  % Set the display format for current cutoff frequency
            inputField1.ValueDisplayFormat = '%d Hz';

            % Add label, image, and input field for speed cutoff frequency
            lblInput2 = uilabel(popup, 'Position', [30, 100, 150, 30], 'Text', 'Speed Cutoff Frequency:');
            lblInput2.BackgroundColor = 'black';
            lblInput2.FontColor = 'white';
            img2 = uiimage(popup, 'Position', [190, 100, 30, 30]);
            img2.ImageSource = fullfile(fileparts(mfilename('fullpath')), 'CutOffFrequency2.png');
            inputField2 = uieditfield(popup, 'numeric', 'Position', [225, 100, 100, 30], ...
                                      'Value', app.omega0speed); % Load stored value
            %inputField2.ValueDisplayFormat = '%.2f Hz';  % Set the display format for speed cutoff frequency
            inputField2.ValueDisplayFormat = '%d Hz';

            % Add a submit button to collect the data and pass the checkboxes and input fields
            btnSubmit = uibutton(popup, 'Position', [100, 50, 100, 30], 'Text', 'Update', ...
                                 'ButtonPushedFcn', @(btnSubmit, event) submitCheckboxData(app, popup, checkbox1, checkbox2, inputField1, inputField2));

            % Add a close button to close the pop-up window
            btnClose = uibutton(popup, 'Position', [220, 50, 100, 30], 'Text', 'Close', ...
                                'ButtonPushedFcn', @(btnClose, event) close(popup));
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)

            connectionType = app.ConnectionTypeDropDown.Value;

            if strcmp(connectionType, "MQTT Broker")
                % MQTT Broker connection
                app.StatusLabel.Text = 'Connecting to MQTT...';
                drawnow;
                try
                    % Attempt to connect to MQTT broker
                    app.MQTTSignal = mqttclient(app.brokerAddress, 'Port', app.port, 'ClientID', app.clientID, 'Username', app.Username, 'Password', app.Password, 'CARootCertificate', app.rootCert);
                    app.Topic = "ESP32/data"; % Global topic
                    app.Topic2 = "ESP32/signals"; % Global topic for receiving signals

                    % Update GUI
                    app.StatusLabel.Text = "Connected to MQTT Broker";
                    app.StatusLabel.FontColor = [0.39, 0.83, 0.07]; % Green
                    % app.DisconnectButton.Enable = 'on';
                    app.ConnectButton.Enable = 'off';
                    if isprop(app, 'ESP32IPLabel')
                        app.ESP32IPLabel.Text = "Connected: MQTT Broker Mode";
                    end
                    if isprop(app, 'ESP32IPEditField')
                        app.ESP32IPEditField.Visible = false;
                    end

                catch ME
                    % Handle connection failure (show the real reason)
                    app.StatusLabel.Text = "Failed to connect to MQTT Broker";
                    app.StatusLabel.FontColor = [1, 0, 0]; % Red
                    app.DutyCycleSlider.Enable = 'off';
                    disp("MQTT Connection Error:");
                    disp(ME.message);
                    return;
                end


            elseif strcmp(connectionType, "ESP32 WiFi")
                app.StatusLabel.Text = 'Connecting to ESP32 WiFi...';
                app.StatusLabel.FontColor = [0.4 0.4 0]; % Yellow
                drawnow;

                try
                    if isprop(app, 'espClient') && ~isempty(app.espClient)
                        clear app.espClient
                        pause(0.1);  % Give system a short delay to clean up
                    end
                    app.espClient = tcpclient('192.168.4.1', 80, 'Timeout', 5);
                    pause(1);  % Give ESP32 time

                    writeline(app.espClient, 'hello');
                    pause(0.5);

                    if app.espClient.NumBytesAvailable > 0
                        response = readline(app.espClient);
                        disp(['ESP32 responded: ', response]);
                        app.StatusLabel.Text = 'Connected to ESP32 WiFi';
                        app.StatusLabel.FontColor = [0 0.6 0];
                        app.ConnectButton.Enable = 'off';
                        % app.DisconnectButton.Enable = 'on';
                    else
                        app.StatusLabel.Text = 'No response from ESP32';
                        app.StatusLabel.FontColor = [1 0 0];
                        return;
                    end

                    % catch
                    %     app.StatusLabel.Text = 'ESP32 WiFi Connection Failed';
                    %     app.StatusLabel.FontColor = [1 0 0];
                    %
                    %     uialert(app.DCMotorControlTrainerAppUIFigure, ...
                    %         'Could not connect to ESP32 WiFi Network', ...
                    %         'Connection Error');
                    %     return;
                    %  end

                catch ME
                    app.StatusLabel.Text = 'ESP32 WiFi Connection Failed';
                    app.StatusLabel.FontColor = [1 0 0];

                    % Show actual error message in command window
                    disp("TCP Connection Error:");
                    disp(ME.message);

                    uialert(app.DCMotorControlTrainerAppUIFigure, ...
                        ['Could not connect to ESP32 at 192.168.4.1:80' newline newline ...
                        'Error: ' ME.message], ...
                        'Connection Error');
                    return;
                end

            end

            % initialization variables after successful connection
            app.UpdateGainsButton.Enable='off';
            app.StopButton.Enable='off';
            app.EditField.Enable='off';
            app.DutyCycleSlider.Enable='on';
            app.SignVtEditField.Enable='off';
            app.Image22.Visible = 'off';
            app.Image25_6.Visible = 'off';
            app.TEditField.Visible = 'off';
            app.Image37.Visible = 'off';
            app.Image13.Visible = 'off';
            app.Image14.Visible = 'off';
            app.Image15.Visible = 'off';
            app.Image57.Visible = 'off';
            app.FilterCoefficientLabel.Visible = 'off';
            app.Image45_5.Visible = 'off';
            app.Image47.Visible = 'off';
            app.Image54.Visible = 'off';
            app.Image55.Visible = 'off';
            app.EditField_2.Visible = 'off';
            app.FilterCoefficientNCheckBox.Visible = 'off';
            app.Image58.Visible = 'off';
            app.NEditField.Visible = 'off';
            app.Image59.Visible = 'off';
            app.Image60.Visible = 'off';
            app.Image15.Visible = 'off';
            app.Image37.Visible = 'on';
            app.Image25_6.Visible = 'on';
            app.Label.Visible = 'on';

            app.filtercurrent = 0; % No filtering by default
            app.filterspeed = 0;
            app.omega0current = 0; % Default cutoff frequencies
            app.omega0speed = 0;
        end

        % Callback function
        function DisconnectButtonPushed(app, event)
            
            connectionType = app.ConnectionTypeDropDown.Value;
            % Close connections
            if strcmp(connectionType, "MQTT Broker")
                if isvalid(app.MQTTSignal)
                    try
                        % unsubscribe(app.MQTTSignal, app.Topic2);
                        unsubscribe(app.MQTTSignal, "Topic", app.Topic2);
                    catch e
                        disp("Unsubscribe failed: " + e.message);
                    end
                    clear app.MQTTSignal
                    disp("MQTT connection closed.");
                end
            elseif strcmp(connectionType, "ESP32 WiFi")
                if isprop(app, 'espClient') && ~isempty(app.espClient)
                    clear app.espClient
                end
            
                % Reset GUI
                app.StatusLabel.Text = 'Disconnected';
                app.StatusLabel.FontColor = [0.5 0.5 0.5]; % Gray
                app.ConnectButton.Enable = 'on';
                app.DisconnectButton.Enable = 'off';
            end
        
            % Reset UI elements
            app.StatusLabel.Text = "Disconnected. Select connection method.";
            app.StatusLabel.FontColor = [1.00, 0.00, 0.00];
        
            % Enable controls
            app.StartButton.Enable = 'on';
            app.StopButton.Enable = 'off';
            app.ConnectButton.Enable = 'on';
            app.DisconnectButton.Enable = 'off';
        end

        % Value changed function: ConnectionTypeDropDown
        function ConnectionTypeDropDownValueChanged(app, event)
            selected = app.ConnectionTypeDropDown.Value;

            if strcmp(selected, 'ESP32 WiFi')
                uialert(app.DCMotorControlTrainerAppUIFigure, ...
                    ['To continue, connect your computer to the ESP32 WiFi network named "ESP32_Hotspot"' newline ...
                     'using your system''s WiFi settings, then press "Connect".'], ...
                    'Connect to ESP32 WiFi');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DCMotorControlTrainerAppUIFigure = uifigure('Visible', 'off');
            app.DCMotorControlTrainerAppUIFigure.Name = 'DC Motor Control Trainer App';
            app.ControlPanel = uipanel(app.DCMotorControlTrainerAppUIFigure);
            app.ControlPanel.Title = 'Control Panel';
            app.StartButton = uibutton(app.ControlPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.ControlPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.LoadButton = uibutton(app.ControlPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.ControlPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SystemOffLabel = uilabel(app.DCMotorControlTrainerAppUIFigure);
            app.SystemOffLabel.Text = 'System Off';
            app.SystemOffLamp = uilamp(app.DCMotorControlTrainerAppUIFigure);
            app.SetVccEditFieldLabel = uilabel(app.DCMotorControlTrainerAppUIFigure);
            app.SetVccEditFieldLabel.Text = 'Set Vcc';
            app.SetVccEditField = uieditfield(app.DCMotorControlTrainerAppUIFigure, 'numeric');
            app.SetVccEditField.ValueDisplayFormat = '%g V';
            app.SetVccEditField.ValueChangedFcn = createCallbackFcn(app, @SetVccEditFieldValueChanged, true);
            app.TabGroup = uitabgroup(app.DCMotorControlTrainerAppUIFigure);
            app.StepTab = uitab(app.TabGroup);
            app.StepTab.Title = 'Step';
            app.AmplitudeEditField = uieditfield(app.StepTab, 'numeric');
            app.AmplitudeEditField.ValueDisplayFormat = '%g V';
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanged, true);
            app.t0EditField = uieditfield(app.StepTab, 'numeric');
            app.t0EditField.ValueDisplayFormat = '%g s';
            app.t0EditField.ValueChangedFcn = createCallbackFcn(app, @t0EditFieldValueChanged, true);
            app.offsetEditField = uieditfield(app.StepTab, 'numeric');
            app.offsetEditField.ValueDisplayFormat = '%g V';
            app.offsetEditField.ValueChangedFcn = createCallbackFcn(app, @offsetEditFieldValueChanged, true);
            app.DutyCycleLabel = uilabel(app.StepTab);
            app.DutyCycleLabel.Text = 'Duty Cycle:';
            app.DutyCycleSlider = uislider(app.StepTab);
            app.DutyCycleSlider.ValueChangedFcn = createCallbackFcn(app, @DutyCycleSliderValueChanged, true);
            app.EditField = uieditfield(app.StepTab, 'text');
            app.warning = uilabel(app.StepTab);
            app.warning.Text = '';
            app.SignVtEditField = uieditfield(app.StepTab, 'numeric');
            app.warningtime = uilabel(app.StepTab);
            app.warningtime.Text = '';
            app.Image21 = uiimage(app.StepTab);
            app.Image21.ImageSource = fullfile(pathToMLAPP, 'Step_Control_Law.png');
            app.Image33_8 = uiimage(app.StepTab);
            app.Image33_8.ImageSource = fullfile(pathToMLAPP, 'gray_line.png');
            app.Image50 = uiimage(app.StepTab);
            app.Image50.ImageSource = fullfile(pathToMLAPP, 'black_W_rectangle.png');
            app.AmplitudeLabel_2 = uilabel(app.StepTab);
            app.AmplitudeLabel_2.Text = 'Amplitude:';
            app.Image51 = uiimage(app.StepTab);
            app.Image51.ImageSource = fullfile(pathToMLAPP, 'Amplitude.png');
            app.Image50_2 = uiimage(app.StepTab);
            app.Image50_2.ImageSource = fullfile(pathToMLAPP, 'black_W_rectangle.png');
            app.Image50_3 = uiimage(app.StepTab);
            app.Image50_3.ImageSource = fullfile(pathToMLAPP, 'black_W_rectangle.png');
            app.OffsetLabel = uilabel(app.StepTab);
            app.OffsetLabel.Text = 'Offset:';
            app.InitialTimeLabel = uilabel(app.StepTab);
            app.InitialTimeLabel.Text = 'Initial Time:';
            app.Image52 = uiimage(app.StepTab);
            app.Image52.ImageSource = fullfile(pathToMLAPP, 't0.png');
            app.Image53 = uiimage(app.StepTab);
            app.Image53.ImageSource = fullfile(pathToMLAPP, 'Delta.png');
            app.Image33_9 = uiimage(app.StepTab);
            app.Image33_9.ImageSource = fullfile(pathToMLAPP, 'gray_line.png');
            app.Image56 = uiimage(app.StepTab);
            app.Image56.ImageSource = fullfile(pathToMLAPP, 'SignVt_white.png');
            app.SineTab = uitab(app.TabGroup);
            app.SineTab.Title = 'Sine';
            app.AmplitudeEditField_2 = uieditfield(app.SineTab, 'numeric');
            app.AmplitudeEditField_2.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditField_2ValueChanged, true);
            app.omegaEditField = uieditfield(app.SineTab, 'numeric');
            app.phiEditField = uieditfield(app.SineTab, 'numeric');
            app.offsetEditField_2 = uieditfield(app.SineTab, 'numeric');
            app.offsetEditField_2.ValueChangedFcn = createCallbackFcn(app, @offsetEditField_2ValueChanged, true);
            app.warning_2 = uilabel(app.SineTab);
            app.warning_2.Text = '';
            app.Image20 = uiimage(app.SineTab);
            app.Image20.ImageSource = fullfile(pathToMLAPP, 'Sinusoidal_Control_Law.png');
            app.Image33_6 = uiimage(app.SineTab);
            app.Image33_6.ImageSource = fullfile(pathToMLAPP, 'gray_line.png');
            app.ButtonGroup_2 = uibuttongroup(app.SineTab);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.ContinuousButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.ContinuousButton_2.Text = 'Continuous';
            app.ContinuousButton_2.Value = true;
            app.DiscreteButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.DiscreteButton_2.Text = 'Discrete';
            app.Image41 = uiimage(app.SineTab);
            app.Image41.ImageSource = fullfile(pathToMLAPP, 'Amplitude.png');
            app.Image42 = uiimage(app.SineTab);
            app.Image42.ImageSource = fullfile(pathToMLAPP, 'omega.png');
            app.Image43 = uiimage(app.SineTab);
            app.Image43.ImageSource = fullfile(pathToMLAPP, 'phi.png');
            app.Image44 = uiimage(app.SineTab);
            app.Image44.ImageSource = fullfile(pathToMLAPP, 'Delta.png');
            app.Image45 = uiimage(app.SineTab);
            app.Image45.ImageSource = fullfile(pathToMLAPP, 'black_W_rectangle.png');
            app.AmplitudeLabel = uilabel(app.SineTab);
            app.AmplitudeLabel.Text = 'Amplitude:';
            app.Image45_2 = uiimage(app.SineTab);
            app.Image45_2.ImageSource = fullfile(pathToMLAPP, 'black_W_rectangle.png');
            app.Image45_3 = uiimage(app.SineTab);
            app.Image45_3.ImageSource = fullfile(pathToMLAPP, 'black_W_rectangle.png');
            app.Image45_4 = uiimage(app.SineTab);
            app.Image45_4.ImageSource = fullfile(pathToMLAPP, 'black_W_rectangle.png');
            app.FrequencyLabel = uilabel(app.SineTab);
            app.FrequencyLabel.Text = 'Frequency:';
            app.phiLabel = uilabel(app.SineTab);
            app.phiLabel.Text = 'Phase Angle:';
            app.offsetLabel = uilabel(app.SineTab);
            app.offsetLabel.Text = 'Offset:';
            app.Image45_5 = uiimage(app.SineTab);
            app.Image45_5.ImageSource = fullfile(pathToMLAPP, 'black_W_rectangle.png');
            app.TLabel_2 = uilabel(app.SineTab);
            app.TLabel_2.Text = 'Period:';
            app.Image47 = uiimage(app.SineTab);
            app.Image47.ImageSource = fullfile(pathToMLAPP, 'Time_Period.png');
            app.EditField_2 = uieditfield(app.SineTab, 'numeric');
            app.Image33_7 = uiimage(app.SineTab);
            app.Image33_7.ImageSource = fullfile(pathToMLAPP, 'gray_line.png');
            app.Image54 = uiimage(app.SineTab);
            app.Image54.ImageSource = fullfile(pathToMLAPP, 't_kT.png');
            app.Image55 = uiimage(app.SineTab);
            app.Image55.ImageSource = fullfile(pathToMLAPP, 'Sinusoidal_Control_Law_Discrete.png');
            app.PIDTab = uitab(app.TabGroup);
            app.PIDTab.Title = 'PID';
            app.wdkEditField = uieditfield(app.PIDTab, 'numeric');
            app.wdkEditField.ValueChangedFcn = createCallbackFcn(app, @wdkEditFieldValueChanged, true);
            app.Image25 = uiimage(app.PIDTab);
            app.Image25.ImageSource = fullfile(pathToMLAPP, 'black_W_rectangle.png');
            app.TargetSpeedLabel = uilabel(app.PIDTab);
            app.TargetSpeedLabel.Text = 'Target Speed:';
            app.Image30 = uiimage(app.PIDTab);
            app.Image30.ImageSource = fullfile(pathToMLAPP, 'wdk.png');
            app.Image33 = uiimage(app.PIDTab);
            app.Image33.ImageSource = fullfile(pathToMLAPP, 'gray_line.png');
            app.Image13 = uiimage(app.PIDTab);
            app.Image13.ImageSource = fullfile(pathToMLAPP, 'Kp_HD.jpg');
            app.Image14 = uiimage(app.PIDTab);
            app.Image14.ImageSource = fullfile(pathToMLAPP, 'Ki_HD.jpg');
            app.Image15 = uiimage(app.PIDTab);
            app.Image15.ImageSource = fullfile(pathToMLAPP, 'Kd_PID_discrete.png');
            app.errorLabel = uilabel(app.PIDTab);
            app.errorLabel.Text = 'error: ';
            app.Image25_2 = uiimage(app.PIDTab);
            app.Image25_2.ImageSource = fullfile(pathToMLAPP, 'black_W_rectangle.png');
            app.ErrorsignalLabel = uilabel(app.PIDTab);
            app.ErrorsignalLabel.Text = 'Error signal:';
            app.Image17 = uiimage(app.PIDTab);
            app.Image17.ImageSource = fullfile(pathToMLAPP, 'error_HD.jpg');
            app.Image31 = uiimage(app.PIDTab);
            app.Image31.ImageSource = fullfile(pathToMLAPP, 'width_black_rectangle.png');
            app.GainsLabel = uilabel(app.PIDTab);
            app.GainsLabel.Text = 'Gains:';
            app.KPEditField = uieditfield(app.PIDTab, 'numeric');
            app.KIEditField = uieditfield(app.PIDTab, 'numeric');
            app.KDEditField = uieditfield(app.PIDTab, 'numeric');
            app.Image27 = uiimage(app.PIDTab);
            app.Image27.ImageSource = fullfile(pathToMLAPP, 'KP_Gain.png');
            app.Image28 = uiimage(app.PIDTab);
            app.Image28.ImageSource = fullfile(pathToMLAPP, 'KI_Gain.png');
            app.Image29 = uiimage(app.PIDTab);
            app.Image29.ImageSource = fullfile(pathToMLAPP, 'KD_Gain.png');
            app.Image25_3 = uiimage(app.PIDTab);
            app.Image25_3.ImageSource = fullfile(pathToMLAPP, 'black_W_rectangle.png');
            app.ProportionalTermLabel = uilabel(app.PIDTab);
            app.ProportionalTermLabel.Text = 'Proportional Term:';
            app.Image25_4 = uiimage(app.PIDTab);
            app.Image25_4.ImageSource = fullfile(pathToMLAPP, 'black_W_rectangle.png');
            app.IntegralTermLabel = uilabel(app.PIDTab);
            app.IntegralTermLabel.Text = 'Integral Term:';
            app.Image25_5 = uiimage(app.PIDTab);
            app.Image25_5.ImageSource = fullfile(pathToMLAPP, 'black_W_rectangle.png');
            app.DerivativeTermLabel = uilabel(app.PIDTab);
            app.DerivativeTermLabel.Text = 'Derivative Term:';
            app.Image33_3 = uiimage(app.PIDTab);
            app.Image33_3.ImageSource = fullfile(pathToMLAPP, 'gray_line.png');
            app.Image33_5 = uiimage(app.PIDTab);
            app.Image33_5.ImageSource = fullfile(pathToMLAPP, 'gray_line.png');
            app.ButtonGroup = uibuttongroup(app.PIDTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ContinuousButton = uiradiobutton(app.ButtonGroup);
            app.ContinuousButton.Text = 'Continuous';
            app.ContinuousButton.Value = true;
            app.DiscreteButton = uiradiobutton(app.ButtonGroup);
            app.DiscreteButton.Text = 'Discrete';
            app.Image22 = uiimage(app.PIDTab);
            app.Image22.ImageSource = fullfile(pathToMLAPP, 'PID_Control_Law.png');
            app.Image25_6 = uiimage(app.PIDTab);
            app.Image25_6.ImageSource = fullfile(pathToMLAPP, 'black_W_rectangle.png');
            app.TLabel = uilabel(app.PIDTab);
            app.TLabel.Text = 'Sample Time:';
            app.Image34 = uiimage(app.PIDTab);
            app.Image34.ImageSource = fullfile(pathToMLAPP, 'PID_control_law_continuous.png');
            app.Image35 = uiimage(app.PIDTab);
            app.Image35.ImageSource = fullfile(pathToMLAPP, 'wdt_PID_continuous.png');
            app.Image36 = uiimage(app.PIDTab);
            app.Image36.ImageSource = fullfile(pathToMLAPP, 'error_PID_continuous.png');
            app.TEditField = uieditfield(app.PIDTab, 'numeric');
            app.TEditField.Limits = [1e-14 Inf];
            app.TEditField.Value = 0.005;
            app.Image37 = uiimage(app.PIDTab);
            app.Image37.ImageSource = fullfile(pathToMLAPP, 'Time_Period.png');
            app.Image38 = uiimage(app.PIDTab);
            app.Image38.ImageSource = fullfile(pathToMLAPP, 'Kp_PID_continuous.png');
            app.Image39 = uiimage(app.PIDTab);
            app.Image39.ImageSource = fullfile(pathToMLAPP, 'ki_PID_continuous.png');
            app.Image40 = uiimage(app.PIDTab);
            app.Image40.ImageSource = fullfile(pathToMLAPP, 'kd_PID_continuous.png');
            app.warning_3 = uilabel(app.PIDTab);
            app.warning_3.Text = '';
            app.Image57 = uiimage(app.PIDTab);
            app.Image57.ImageSource = fullfile(pathToMLAPP, 'black_W_rectangle.png');
            app.FilterCoefficientLabel = uilabel(app.PIDTab);
            app.FilterCoefficientLabel.Text = 'Filter Coefficient:';
            app.NEditField = uieditfield(app.PIDTab, 'numeric');
            app.NEditField.Value = 1;
            app.Image58 = uiimage(app.PIDTab);
            app.Image58.ImageSource = fullfile(pathToMLAPP, 'Filter_Coefficient_N.png');
            app.FilterCoefficientNCheckBox = uicheckbox(app.PIDTab);
            app.FilterCoefficientNCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterCoefficientNCheckBoxValueChanged, true);
            app.FilterCoefficientNCheckBox.Text = 'Filter Coefficient (N)';
            app.Image59 = uiimage(app.PIDTab);
            app.Image59.ImageSource = fullfile(pathToMLAPP, 'wdk.png');
            app.Image60 = uiimage(app.PIDTab);
            app.Image60.ImageSource = fullfile(pathToMLAPP, 'VDk_discrete.png');
            app.UpdateGainsButton = uibutton(app.PIDTab, 'push');
            app.UpdateGainsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateGainsButtonPushed, true);
            app.UpdateGainsButton.Text = 'Update Gains';
            app.DigitalFiltersButton = uibutton(app.PIDTab, 'push');
            app.DigitalFiltersButton.ButtonPushedFcn = createCallbackFcn(app, @DigitalFiltersButtonPushed, true);
            app.DigitalFiltersButton.Text = 'Digital Filters';
            app.Label = uilabel(app.PIDTab);
            app.Label.Text = '0.005';
            app.DCMotorSignalsLabel = uilabel(app.DCMotorControlTrainerAppUIFigure);
            app.DCMotorSignalsLabel.Text = 'DC Motor Signals';
            app.TabGroup2 = uitabgroup(app.DCMotorControlTrainerAppUIFigure);
            app.SpeedVoltageTab = uitab(app.TabGroup2);
            app.SpeedVoltageTab.Title = 'Speed & Voltage';
            app.UIAxes2 = uiaxes(app.SpeedVoltageTab);
            title(app.UIAxes2, 'Speed')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Speed (rad/s)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.YLim = [-20 20];
            app.UIAxes3 = uiaxes(app.SpeedVoltageTab);
            title(app.UIAxes3, 'Voltage')
            xlabel(app.UIAxes3, 'Time (s)')
            ylabel(app.UIAxes3, 'Voltaje (V)')
            zlabel(app.UIAxes3, 'Z')
            app.CurrentErrorTab = uitab(app.TabGroup2);
            app.CurrentErrorTab.Title = 'Current & Error';
            app.UIAxes4 = uiaxes(app.CurrentErrorTab);
            title(app.UIAxes4, 'Error')
            xlabel(app.UIAxes4, 'Time')
            ylabel(app.UIAxes4, 'Error')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes = uiaxes(app.CurrentErrorTab);
            title(app.UIAxes, 'Current')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Current (mA)')
            zlabel(app.UIAxes, 'Z')
            app.ConnectionTypeDropDownLabel = uilabel(app.DCMotorControlTrainerAppUIFigure);
            app.ConnectionTypeDropDownLabel.Text = 'Connection Type';
            app.ConnectionTypeDropDown = uidropdown(app.DCMotorControlTrainerAppUIFigure);
            app.ConnectionTypeDropDown.Items = {'MQTT Broker', 'ESP32 WiFi'};
            app.ConnectionTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ConnectionTypeDropDownValueChanged, true);
            app.ConnectionTypeDropDown.Value = 'MQTT Broker';
            app.StatusLabel = uilabel(app.DCMotorControlTrainerAppUIFigure);
            app.StatusLabel.Text = 'StatusLabel';
            app.ConnectButton = uibutton(app.DCMotorControlTrainerAppUIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            % Show the figure after all components are created
            app.DCMotorControlTrainerAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ESP32_MQTT_DCmotor_New_Certificate_V1_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DCMotorControlTrainerAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DCMotorControlTrainerAppUIFigure)
        end
    end
end